classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OldSimulationTab                matlab.ui.container.Tab
        LoadandShowPreviousSimulationButton  matlab.ui.control.Button
        HugeerrorlimitEditField_2Label  matlab.ui.control.Label
        ShowHugeError                   matlab.ui.control.NumericEditField
        SmallerrorlimitEditField_2Label  matlab.ui.control.Label
        ShowSmallError                  matlab.ui.control.NumericEditField
        ProbabilitySliderLabel_2        matlab.ui.control.Label
        ShowHugeErrorProb               matlab.ui.control.Slider
        ProbabilitySlider_2Label_2      matlab.ui.control.Label
        ShowSmallErrorProb              matlab.ui.control.Slider
        DaysofsimulationEditField_2Label  matlab.ui.control.Label
        ShowDays                        matlab.ui.control.NumericEditField
        LeakLampLabel                   matlab.ui.control.Label
        ShowLeakLamp                    matlab.ui.control.Lamp
        HugeerroronLampLabel            matlab.ui.control.Label
        HugeerroronLamp                 matlab.ui.control.Lamp
        WCerroronLampLabel              matlab.ui.control.Label
        WCerroronLamp                   matlab.ui.control.Lamp
        ShowererroronLampLabel          matlab.ui.control.Label
        ShowererroronLamp               matlab.ui.control.Lamp
        PreparefooderroronLampLabel     matlab.ui.control.Label
        PreparefooderroronLamp          matlab.ui.control.Lamp
        DodisheserroronLampLabel        matlab.ui.control.Label
        DodisheserroronLamp             matlab.ui.control.Lamp
        OtherserroronLampLabel          matlab.ui.control.Label
        OtherserroronLamp               matlab.ui.control.Lamp
        DochoreserroronLampLabel        matlab.ui.control.Label
        DochoreserroronLamp             matlab.ui.control.Lamp
        WashclotheserroronLampLabel     matlab.ui.control.Label
        WashclotheserroronLamp          matlab.ui.control.Lamp
        TrainingDaysEditFieldLabel      matlab.ui.control.Label
        TrainingDaysEditField           matlab.ui.control.NumericEditField
        NewSimulationTab                matlab.ui.container.Tab
        HugeerrorlimitEditFieldLabel    matlab.ui.control.Label
        HugeerrorlimitEditField         matlab.ui.control.NumericEditField
        SmallerrorlimitEditFieldLabel   matlab.ui.control.Label
        SmallerrorlimitEditField        matlab.ui.control.NumericEditField
        ProbabilitySliderLabel          matlab.ui.control.Label
        HugeErrorProb                   matlab.ui.control.Slider
        ProbabilitySlider_2Label        matlab.ui.control.Label
        SmallErrorProb                  matlab.ui.control.Slider
        DaysofsimulationEditFieldLabel  matlab.ui.control.Label
        DaysofsimulationEditField       matlab.ui.control.NumericEditField
        RunNewSimulationButton          matlab.ui.control.Button
        LeakLampLabel_2                 matlab.ui.control.Label
        LeakLamp                        matlab.ui.control.Lamp
        HugeerroronLamp_2Label          matlab.ui.control.Label
        HugeerroronLamp_2               matlab.ui.control.Lamp
        WCerroronLamp_2Label            matlab.ui.control.Label
        WCerroronLamp_2                 matlab.ui.control.Lamp
        ShowererroronLamp_2Label        matlab.ui.control.Label
        ShowererroronLamp_2             matlab.ui.control.Lamp
        PreparefooderroronLamp_2Label   matlab.ui.control.Label
        PreparefooderroronLamp_2        matlab.ui.control.Lamp
        DodisheserroronLamp_2Label      matlab.ui.control.Label
        DodisheserroronLamp_2           matlab.ui.control.Lamp
        OtherserroronLamp_2Label        matlab.ui.control.Label
        OtherserroronLamp_2             matlab.ui.control.Lamp
        DochoreserroronLamp_2Label      matlab.ui.control.Label
        DochoreserroronLamp_2           matlab.ui.control.Lamp
        WashclotheserroronLamp_2Label   matlab.ui.control.Label
        WashclotheserroronLamp_2        matlab.ui.control.Lamp
        DaysofTrainingSpinnerLabel      matlab.ui.control.Label
        DaysofTrainingSpinner           matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RundetectionalgorithmroutinewithprevioussimulationButtonPushed(app, event)
            app.SendnotificationLamp.Color=[0,1,0];
        end

        % Callback function
        function EnablemailnotificationSwitchValueChanged(app, event)
            value = app.EnablemailnotificationSwitch.Value;
            
            if strcmp(value,'On')
                app.SendnotificationLamp.Color=[0,1,0];
                mailAdd = inputdlg('Put your mail where you want to receive the notification');
                app.mail.Visible=1;
                app.showMail.Visible=1;
                app.mail.Text=mailAdd;
                app.showMail.Text=mailAdd;
            end
            if strcmp(value,'Off')
                app.SendnotificationLamp.Color=[1,0,0];
                app.mail.Visible=0;
                app.showMail.Visible=0;
            end
        end

        % Button pushed function: 
        % LoadandShowPreviousSimulationButton
        function LoadandShowPreviousSimulationButtonPushed(app, event)
            %Configurates the show lamps
            flags=load('ErrorFlags.mat');
            flags=flags.Error;
            Fatal=flags.Fatal;
            Wc=flags.Wc;
            Show=flags.Show;
            Food=flags.Food;
            Kitch=flags.Kitch;
            Other=flags.Other;
            Cleani=flags.Cleani;
            Wash=flags.Wash;
            app.HugeerroronLamp.Color=[1-Fatal,Fatal,0];
            app.WCerroronLamp.Color=[1-Wc,Wc,0];
            app.ShowererroronLamp.Color=[1-Show,Show,0];
            app.PreparefooderroronLamp.Color=[1-Food,Food,0];
            app.DodisheserroronLamp.Color=[1-Kitch,Kitch,0];
            app.OtherserroronLamp.Color=[1-Other,Other,0];
            app.DochoreserroronLamp.Color=[1-Cleani,Cleani,0];
            app.WashclotheserroronLamp.Color=[1-Wash,Wash,0];
            %Configurate the numeric information
            SimInfo=load('SimInfo.mat');
            SimInfo=SimInfo.SimInfo;
            app.ShowDays.Value=SimInfo.Nd;
            app.ShowHugeError.Value=SimInfo.MB;
            app.ShowSmallError.Value=SimInfo.MS;
            app.ShowHugeErrorProb.Value=SimInfo.PB;
            app.ShowSmallErrorProb.Value=SimInfo.PS;
            app.TrainingDaysEditField.Value=SimInfo.Td;
            %Show simulation data
            
            [fug,info]=worktest(SimInfo.Nd,0,...
                SimInfo.Td,SimInfo.MB,SimInfo.MS,SimInfo.PB,SimInfo.PS);
            if fug
                app.LeakLampLabel.Text=info;
            end
            app.ShowLeakLamp.Color=[1-fug,fug,0];
        end

        % Value changed function: ShowDays
        function ShowDaysValueChanged(app, event)
            %value = app.ShowDays.Value;
        end

        % Value changed function: DaysofsimulationEditField
        function DaysofsimulationEditFieldValueChanged(app, event)
            value = app.DaysofsimulationEditField.Value;
            app.DaysofTrainingSpinner.Enable=1;
            if value==2
                app.DaysofTrainingSpinner.Value=1;
                app.DaysofTrainingSpinner.Enable=0;
                app.DaysofTrainingSpinner.Limits=[1,2];
            else
                app.DaysofTrainingSpinner.Limits=[1,value-1];
            end
        end

        % Button pushed function: RunNewSimulationButton
        function RunNewSimulationButtonPushed(app, event)
            app.RunNewSimulationButton.Text='Runing simulation';
            app.RunNewSimulationButton.Enable=0;
            msgbox('It started a simulation');
            %Calls and runs simulation
            [fug,info]=worktest(app.DaysofsimulationEditField.Value,...
                1,app.DaysofTrainingSpinner.Value,...
                app.HugeerrorlimitEditField.Value,...
                app.HugeErrorProb.Value,...
                app.SmallerrorlimitEditField.Value,...
                app.SmallErrorProb.Value);
            %Shows the result
            if fug
                app.LeakLampLabel_2.Text=info;
            end
            app.LeakLamp.Color=[1-fug,fug,0];
            %Show simulation information
            flags=load('ErrorFlags.mat');
            flags=flags.Error;
            Fatal=flags.Fatal;
            Wc=flags.Wc;
            Show=flags.Show;
            Food=flags.Food;
            Kitch=flags.Kitch;
            Other=flags.Other;
            Cleani=flags.Cleani;
            Wash=flags.Wash;
            app.HugeerroronLamp_2.Color=[1-Fatal,Fatal,0];
            app.WCerroronLamp_2.Color=[1-Wc,Wc,0];
            app.ShowererroronLamp_2.Color=[1-Show,Show,0];
            app.PreparefooderroronLamp_2.Color=[1-Food,Food,0];
            app.DodisheserroronLamp_2.Color=[1-Kitch,Kitch,0];
            app.OtherserroronLamp_2.Color=[1-Other,Other,0];
            app.DochoreserroronLamp_2.Color=[1-Cleani,Cleani,0];
            app.WashclotheserroronLamp_2.Color=[1-Wash,Wash,0];
            app.RunNewSimulationButton.Text='Run New Simulation';
            app.RunNewSimulationButton.Enable=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OldSimulationTab = uitab(app.TabGroup);
            app.OldSimulationTab.Title = 'Old Simulation';
            app.LoadandShowPreviousSimulationButton = uibutton(app.OldSimulationTab, 'push');
            app.LoadandShowPreviousSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadandShowPreviousSimulationButtonPushed, true);
            app.LoadandShowPreviousSimulationButton.Text = 'Load and Show Previous Simulation';
            app.HugeerrorlimitEditField_2Label = uilabel(app.OldSimulationTab);
            app.HugeerrorlimitEditField_2Label.Text = 'Huge error limit';
            app.ShowHugeError = uieditfield(app.OldSimulationTab, 'numeric');
            app.ShowHugeError.Editable = 'off';
            app.SmallerrorlimitEditField_2Label = uilabel(app.OldSimulationTab);
            app.SmallerrorlimitEditField_2Label.Text = 'Small error limit';
            app.ShowSmallError = uieditfield(app.OldSimulationTab, 'numeric');
            app.ShowSmallError.Editable = 'off';
            app.ProbabilitySliderLabel_2 = uilabel(app.OldSimulationTab);
            app.ProbabilitySliderLabel_2.Enable = 'off';
            app.ProbabilitySliderLabel_2.Text = 'Probability';
            app.ShowHugeErrorProb = uislider(app.OldSimulationTab);
            app.ShowHugeErrorProb.Enable = 'off';
            app.ProbabilitySlider_2Label_2 = uilabel(app.OldSimulationTab);
            app.ProbabilitySlider_2Label_2.Enable = 'off';
            app.ProbabilitySlider_2Label_2.Text = 'Probability';
            app.ShowSmallErrorProb = uislider(app.OldSimulationTab);
            app.ShowSmallErrorProb.Enable = 'off';
            app.DaysofsimulationEditField_2Label = uilabel(app.OldSimulationTab);
            app.DaysofsimulationEditField_2Label.Text = 'Days of simulation';
            app.ShowDays = uieditfield(app.OldSimulationTab, 'numeric');
            app.ShowDays.ValueChangedFcn = createCallbackFcn(app, @ShowDaysValueChanged, true);
            app.ShowDays.Editable = 'off';
            app.LeakLampLabel = uilabel(app.OldSimulationTab);
            app.LeakLampLabel.Text = 'Leak';
            app.ShowLeakLamp = uilamp(app.OldSimulationTab);
            app.HugeerroronLampLabel = uilabel(app.OldSimulationTab);
            app.HugeerroronLampLabel.Text = 'Huge error on';
            app.HugeerroronLamp = uilamp(app.OldSimulationTab);
            app.WCerroronLampLabel = uilabel(app.OldSimulationTab);
            app.WCerroronLampLabel.Text = 'WC error on';
            app.WCerroronLamp = uilamp(app.OldSimulationTab);
            app.ShowererroronLampLabel = uilabel(app.OldSimulationTab);
            app.ShowererroronLampLabel.Text = 'Shower error on';
            app.ShowererroronLamp = uilamp(app.OldSimulationTab);
            app.PreparefooderroronLampLabel = uilabel(app.OldSimulationTab);
            app.PreparefooderroronLampLabel.Text = 'Prepare food error on';
            app.PreparefooderroronLamp = uilamp(app.OldSimulationTab);
            app.DodisheserroronLampLabel = uilabel(app.OldSimulationTab);
            app.DodisheserroronLampLabel.Text = 'Do dishes error on';
            app.DodisheserroronLamp = uilamp(app.OldSimulationTab);
            app.OtherserroronLampLabel = uilabel(app.OldSimulationTab);
            app.OtherserroronLampLabel.Text = 'Others error on';
            app.OtherserroronLamp = uilamp(app.OldSimulationTab);
            app.DochoreserroronLampLabel = uilabel(app.OldSimulationTab);
            app.DochoreserroronLampLabel.Text = 'Do chores error on';
            app.DochoreserroronLamp = uilamp(app.OldSimulationTab);
            app.WashclotheserroronLampLabel = uilabel(app.OldSimulationTab);
            app.WashclotheserroronLampLabel.Text = 'Wash clothes error on';
            app.WashclotheserroronLamp = uilamp(app.OldSimulationTab);
            app.TrainingDaysEditFieldLabel = uilabel(app.OldSimulationTab);
            app.TrainingDaysEditFieldLabel.Text = 'Training Days';
            app.TrainingDaysEditField = uieditfield(app.OldSimulationTab, 'numeric');
            app.TrainingDaysEditField.Editable = 'off';
            app.NewSimulationTab = uitab(app.TabGroup);
            app.NewSimulationTab.Title = 'New Simulation';
            app.HugeerrorlimitEditFieldLabel = uilabel(app.NewSimulationTab);
            app.HugeerrorlimitEditFieldLabel.Text = 'Huge error limit';
            app.HugeerrorlimitEditField = uieditfield(app.NewSimulationTab, 'numeric');
            app.SmallerrorlimitEditFieldLabel = uilabel(app.NewSimulationTab);
            app.SmallerrorlimitEditFieldLabel.Text = 'Small error limit';
            app.SmallerrorlimitEditField = uieditfield(app.NewSimulationTab, 'numeric');
            app.ProbabilitySliderLabel = uilabel(app.NewSimulationTab);
            app.ProbabilitySliderLabel.Text = 'Probability';
            app.HugeErrorProb = uislider(app.NewSimulationTab);
            app.ProbabilitySlider_2Label = uilabel(app.NewSimulationTab);
            app.ProbabilitySlider_2Label.Text = 'Probability';
            app.SmallErrorProb = uislider(app.NewSimulationTab);
            app.DaysofsimulationEditFieldLabel = uilabel(app.NewSimulationTab);
            app.DaysofsimulationEditFieldLabel.Text = 'Days of simulation';
            app.DaysofsimulationEditField = uieditfield(app.NewSimulationTab, 'numeric');
            app.DaysofsimulationEditField.Limits = [2 Inf];
            app.DaysofsimulationEditField.RoundFractionalValues = 'on';
            app.DaysofsimulationEditField.ValueChangedFcn = createCallbackFcn(app, @DaysofsimulationEditFieldValueChanged, true);
            app.DaysofsimulationEditField.Value = 2;
            app.RunNewSimulationButton = uibutton(app.NewSimulationTab, 'push');
            app.RunNewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunNewSimulationButtonPushed, true);
            app.RunNewSimulationButton.Text = 'Run New Simulation';
            app.LeakLampLabel_2 = uilabel(app.NewSimulationTab);
            app.LeakLampLabel_2.Text = 'Leak';
            app.LeakLamp = uilamp(app.NewSimulationTab);
            app.HugeerroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.HugeerroronLamp_2Label.Text = 'Huge error on';
            app.HugeerroronLamp_2 = uilamp(app.NewSimulationTab);
            app.WCerroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.WCerroronLamp_2Label.Text = 'WC error on';
            app.WCerroronLamp_2 = uilamp(app.NewSimulationTab);
            app.ShowererroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.ShowererroronLamp_2Label.Text = 'Shower error on';
            app.ShowererroronLamp_2 = uilamp(app.NewSimulationTab);
            app.PreparefooderroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.PreparefooderroronLamp_2Label.Text = 'Prepare food error on';
            app.PreparefooderroronLamp_2 = uilamp(app.NewSimulationTab);
            app.DodisheserroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.DodisheserroronLamp_2Label.Text = 'Do dishes error on';
            app.DodisheserroronLamp_2 = uilamp(app.NewSimulationTab);
            app.OtherserroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.OtherserroronLamp_2Label.Text = 'Others error on';
            app.OtherserroronLamp_2 = uilamp(app.NewSimulationTab);
            app.DochoreserroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.DochoreserroronLamp_2Label.Text = 'Do chores error on';
            app.DochoreserroronLamp_2 = uilamp(app.NewSimulationTab);
            app.WashclotheserroronLamp_2Label = uilabel(app.NewSimulationTab);
            app.WashclotheserroronLamp_2Label.Text = 'Wash clothes error on';
            app.WashclotheserroronLamp_2 = uilamp(app.NewSimulationTab);
            app.DaysofTrainingSpinnerLabel = uilabel(app.NewSimulationTab);
            app.DaysofTrainingSpinnerLabel.Text = 'Days of Training';
            app.DaysofTrainingSpinner = uispinner(app.NewSimulationTab);
            app.DaysofTrainingSpinner.Enable = 'off';
            app.DaysofTrainingSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end